classdef disttime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        xSliderLabel     matlab.ui.control.Label
        xSlider          matlab.ui.control.Slider
        ySliderLabel     matlab.ui.control.Label
        ySlider          matlab.ui.control.Slider
        yminSliderLabel  matlab.ui.control.Label
        yminSlider       matlab.ui.control.Slider
        ymaxSliderLabel  matlab.ui.control.Label
        ymaxSlider       matlab.ui.control.Slider
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataCube % Description
        evelv % Description
        nt % Description
    end
    
    methods (Access = public)


        function results = loaddata(app)
            bdir='/Users/mikegriffiths/proj';
            rdirectory='/washmc-data/uni1';
            directory=[bdir,rdirectory,'/'];
            matfile=[directory,'hhzverustime.mat'];
            %matfile=[app.path,app.file];
            app.DataCube=load(matfile);
            
            app.evelv=app.DataCube.evelv2Mm_v;
            sz=size(app.evelv)
            app.nt=sz(3); %635 for uni4   %1070 for uni1            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loaddata(app)
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            x = app.xSlider.Value;
            y = app.ySlider.Value;
            
            dat=app.evelv(int32(x),int32(y),:)
            dat=reshape(dat,[app.nt,1])
            plot(app.UIAxes,dat)
            app.UIAxes.YLim=[app.yminSlider.Value*min(dat) app.ymaxSlider.Value*max(dat)];
            app.UIAxes.XLim=[0 app.nt];
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            value = app.ySlider.Value;
            x = app.xSlider.Value;
            y = app.ySlider.Value;
            
            dat=app.evelv(int32(x),int32(y),:)
            dat=reshape(dat,[app.nt,1])
            plot(app.UIAxes,dat)
            app.UIAxes.YLim=[app.yminSlider.Value*min(dat) app.ymaxSlider.Value*max(dat)];
            app.UIAxes.XLim=[0 app.nt];
        end

        % Value changed function: yminSlider
        function yminSliderValueChanged(app, event)
            value = app.yminSlider.Value;
            
            x = app.xSlider.Value;
            y = app.ySlider.Value;
            
            dat=app.evelv(int32(x),int32(y),:)
            dat=reshape(dat,[app.nt,1])
            plot(app.UIAxes,dat)
            app.UIAxes.YLim=[app.yminSlider.Value*min(dat) app.ymaxSlider.Value*max(dat)];
            app.UIAxes.XLim=[0 app.nt];
            
        end

        % Value changed function: ymaxSlider
        function ymaxSliderValueChanged(app, event)
            value = app.ymaxSlider.Value;
            
            x = app.xSlider.Value;
            y = app.ySlider.Value;
            
            dat=app.evelv(int32(x),int32(y),:)
            dat=reshape(dat,[app.nt,1])
            plot(app.UIAxes,dat)
            app.UIAxes.YLim=[app.yminSlider.Value*min(dat) app.ymaxSlider.Value*max(dat)];
            app.UIAxes.XLim=[0 app.nt];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [1 124];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.Value = 1;
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [1 124];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.Value = 1;
            app.yminSliderLabel = uilabel(app.UIFigure);
            app.yminSliderLabel.Text = 'ymin';
            app.yminSlider = uislider(app.UIFigure);
            app.yminSlider.Limits = [0.01 1.5];
            app.yminSlider.ValueChangedFcn = createCallbackFcn(app, @yminSliderValueChanged, true);
            app.yminSlider.Value = 1;
            app.ymaxSliderLabel = uilabel(app.UIFigure);
            app.ymaxSliderLabel.Text = 'ymax';
            app.ymaxSlider = uislider(app.UIFigure);
            app.ymaxSlider.Limits = [0.01 1.5];
            app.ymaxSlider.ValueChangedFcn = createCallbackFcn(app, @ymaxSliderValueChanged, true);
            app.ymaxSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 124];
            app.UIAxes.YLim = [0 124];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = disttime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end