classdef pod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        XSliderLabel       matlab.ui.control.Label
        XSlider            matlab.ui.control.Slider
        ySliderLabel       matlab.ui.control.Label
        ySlider            matlab.ui.control.Slider
        AminSliderLabel    matlab.ui.control.Label
        AminSlider         matlab.ui.control.Slider
        AmaxSliderLabel    matlab.ui.control.Label
        AmaxSlider         matlab.ui.control.Slider
        PHIminSliderLabel  matlab.ui.control.Label
        PHIminSlider       matlab.ui.control.Slider
        PHImaxSliderLabel  matlab.ui.control.Label
        PHImaxSlider       matlab.ui.control.Slider
        tSliderLabel       matlab.ui.control.Label
        tSlider            matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataCube % Description
        evelv % Description
        nt % Description
        U % Description
        A_s % Description
        PHI_s % Description
        k % Description
        PHI % Description
        st % Description
    end
    
    methods (Access = public)
        
        function results = loaddata(app)
            bdir='/Users/mikegriffiths/proj';
            rdirectory='/washmc-data/uni1';
            directory=[bdir,rdirectory,'/'];
            matfile=[directory,'hhzverustime.mat'];
            %matfile=[app.path,app.file];
            app.DataCube=load(matfile);
            
            app.evelv=app.DataCube.evelchrom_vh;
            app.nt=1070; %635 for uni4   %1070 for uni1           
        end
        
        function results = podan(app)
            S=app.evelv(:,:,1:app.nt);
            %proper orthogonal decomposition
            % https://depositonce.tu-berlin.de/bitstream/11303/9456/5/podnotes_aiaa2019.pdf

            % Create snapshot matrix
            Nt = length(S(1,1,:));
            S = reshape(permute(S, [3 2 1]), Nt, [ ]); % Reshape data into a matrix S with Nt rows
            app.U = S - repmat(mean(S,1), Nt, 1); % Subtract the temporal mean from each row

            % Create correlation matrix
            C_s = (app.U*(app.U)')/(Nt-1);


            % Solve eigenvalue problem
            [app.A_s LAM_s] = eig(C_s,'vector');
            % Sort eigenvalues and eigenvectors
            [lambda_s,ilam_s] = sort(LAM_s,'descend');
            app.A_s = app.A_s(:, ilam_s); % These are the temporal modes
            % Calculate spatial coefficients
            app.PHI_s = (app.U)'*app.A_s;            
        end
        
        function results = construct(app)
            %app.k = 105; % for example
            
            % Normalization to match direct and snapshot modes (optional)
            app.PHI = normc(app.PHI_s); % Spatial modes
            A = app.U*app.PHI; % Time coefficients
            Utilde_k = A(:,app.k)*app.PHI(:,app.k)'; % Reconstruction on mode k

            app.st=reshape(Utilde_k,[app.nt,124,124]);  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bdir='/Users/mikegriffiths/proj';
            rdirectory='/washmc-data/uni1';
            directory=[bdir,rdirectory,'/'];
            matfile=[directory,'hhzverustime.mat'];
            %app.file=matfile
            %app.path=directory

            loaddata(app)
            app.k=5;
            podan(app)
            construct(app)            
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = app.XSlider.Value;
            
            x = app.XSlider.Value;
            y = app.ySlider.Value;
            t = app.tSlider.Value;
            
            dat1=app.A_s(:,int32(t));
            dat1=reshape(dat1,[app.nt,1]);
            
            plot(app.UIAxes,dat1)
            app.UIAxes.YLim=[app.AminSlider.Value*min(dat1) app.AmaxSlider.Value*max(dat1)];
            app.UIAxes.XLim=[0 app.nt]; 
            
            dat2=app.PHI_s(:,:);
            dat2=reshape(dat2,[124,124,app.nt]);
            dat2=reshape(dat2(int32(x),int32(y),:),[app.nt,1]);
            dat2=reshape(dat2,[app.nt,1]);
            plot(app.UIAxes2,dat2)
            app.UIAxes2.YLim=[app.PHIminSlider.Value*min(dat2) app.PHImaxSlider.Value*max(dat2)];
            app.UIAxes2.XLim=[0 app.nt];  


        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            
            value = app.ySlider.Value;
            x = app.XSlider.Value;
            y = app.ySlider.Value;
            t = app.tSlider.Value;
            
            dat1=app.A_s(:,int32(t));
            dat1=reshape(dat1,[app.nt,1]);
            
            plot(app.UIAxes,dat1)
            app.UIAxes.YLim=[app.AminSlider.Value*min(dat1) app.AmaxSlider.Value*max(dat1)];
            app.UIAxes.XLim=[0 app.nt]; 
            
            dat2=app.PHI_s(:,:);
            dat2=reshape(dat2,[124,124,app.nt]);
            dat2=reshape(dat2(int32(x),int32(y),:),[app.nt,1]);
            dat2=reshape(dat2,[app.nt,1]);
            plot(app.UIAxes2,dat2)
            app.UIAxes2.YLim=[app.PHIminSlider.Value*min(dat2) app.PHImaxSlider.Value*max(dat2)];
            app.UIAxes2.XLim=[0 app.nt];  
            
        end

        % Value changed function: tSlider
        function tSliderValueChanged(app, event)
            value = app.tSlider.Value;

            x = app.XSlider.Value;
            y = app.ySlider.Value;
            t = app.tSlider.Value;
            
            dat1=app.A_s(:,int32(t));
            dat1=reshape(dat1,[app.nt,1]);
            
            plot(app.UIAxes,dat1)
            app.UIAxes.YLim=[app.AminSlider.Value*min(dat1) app.AmaxSlider.Value*max(dat1)];
            app.UIAxes.XLim=[0 app.nt]; 
            
            dat2=app.PHI_s(:,:);
            dat2=reshape(dat2,[124,124,app.nt]);
            dat2=reshape(dat2(int32(x),int32(y),:),[app.nt,1]);
            dat2=reshape(dat2,[app.nt,1]);
            plot(app.UIAxes2,dat2)
            app.UIAxes2.YLim=[app.PHIminSlider.Value*min(dat2) app.PHImaxSlider.Value*max(dat2)];
            app.UIAxes2.XLim=[0 app.nt];             
        end

        % Value changed function: PHImaxSlider
        function PHImaxSliderValueChanged(app, event)
            value = app.PHImaxSlider.Value;

            x = app.XSlider.Value;
            y = app.ySlider.Value;
            t = app.tSlider.Value;
            
            dat1=app.A_s(:,int32(t));
            dat1=reshape(dat1,[app.nt,1]);
            
            plot(app.UIAxes,dat1)
            app.UIAxes.YLim=[app.AminSlider.Value*min(dat1) app.AmaxSlider.Value*max(dat1)];
            app.UIAxes.XLim=[0 app.nt]; 
            
            dat2=app.PHI_s(:,:);
            dat2=reshape(dat2,[124,124,app.nt]);
            dat2=reshape(dat2(int32(x),int32(y),:),[app.nt,1]);
            dat2=reshape(dat2,[app.nt,1]);
            plot(app.UIAxes2,dat2)
            app.UIAxes2.YLim=[app.PHIminSlider.Value*min(dat2) app.PHImaxSlider.Value*max(dat2)];
            app.UIAxes2.XLim=[0 app.nt];                
            
        end

        % Value changed function: PHIminSlider
        function PHIminSliderValueChanged(app, event)
            value = app.PHIminSlider.Value;
            
            x = app.XSlider.Value;
            y = app.ySlider.Value;
            t = app.tSlider.Value;
            
            dat1=app.A_s(:,int32(t));
            dat1=reshape(dat1,[app.nt,1]);
            
            plot(app.UIAxes,dat1)
            app.UIAxes.YLim=[app.AminSlider.Value*min(dat1) app.AmaxSlider.Value*max(dat1)];
            app.UIAxes.XLim=[0 app.nt]; 
            
            dat2=app.PHI_s(:,:);
            dat2=reshape(dat2,[124,124,app.nt]);
            dat2=reshape(dat2(int32(x),int32(y),:),[app.nt,1]);
            dat2=reshape(dat2,[app.nt,1]);
            plot(app.UIAxes2,dat2)
            app.UIAxes2.YLim=[app.PHIminSlider.Value*min(dat2) app.PHImaxSlider.Value*max(dat2)];
            app.UIAxes2.XLim=[0 app.nt];              
            
        end

        % Value changed function: AmaxSlider
        function AmaxSliderValueChanged(app, event)
            value = app.AmaxSlider.Value;
            
            x = app.XSlider.Value;
            y = app.ySlider.Value;
            t = app.tSlider.Value;
            
            dat1=app.A_s(:,int32(t));
            dat1=reshape(dat1,[app.nt,1]);
            
            plot(app.UIAxes,dat1)
            app.UIAxes.YLim=[app.AminSlider.Value*min(dat1) app.AmaxSlider.Value*max(dat1)];
            app.UIAxes.XLim=[0 app.nt]; 
            
            dat2=app.PHI_s(:,:);
            dat2=reshape(dat2,[124,124,app.nt]);
            dat2=reshape(dat2(int32(x),int32(y),:),[app.nt,1]);
            dat2=reshape(dat2,[app.nt,1]);
            plot(app.UIAxes2,dat2)
            app.UIAxes2.YLim=[app.PHIminSlider.Value*min(dat2) app.PHImaxSlider.Value*max(dat2)];
            app.UIAxes2.XLim=[0 app.nt];              
        end

        % Value changed function: AminSlider
        function AminSliderValueChanged(app, event)
            value = app.AminSlider.Value;
            x = app.XSlider.Value;
            y = app.ySlider.Value;
            t = app.tSlider.Value;
            
            dat1=app.A_s(:,int32(t));
            dat1=reshape(dat1,[app.nt,1]);
            
            plot(app.UIAxes,dat1)
            app.UIAxes.YLim=[app.AminSlider.Value*min(dat1) app.AmaxSlider.Value*max(dat1)];
            app.UIAxes.XLim=[0 app.nt]; 
            
            dat2=app.PHI_s(:,:);
            dat2=reshape(dat2,[124,124,app.nt]);
            dat2=reshape(dat2(int32(x),int32(y),:),[app.nt,1]);
            dat2=reshape(dat2,[app.nt,1]);
            plot(app.UIAxes2,dat2)
            app.UIAxes2.YLim=[app.PHIminSlider.Value*min(dat2) app.PHImaxSlider.Value*max(dat2)];
            app.UIAxes2.XLim=[0 app.nt];             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [1 124];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.Value = 1;
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [1 124];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.Value = 1;
            app.AminSliderLabel = uilabel(app.UIFigure);
            app.AminSliderLabel.Text = 'Amin';
            app.AminSlider = uislider(app.UIFigure);
            app.AminSlider.Limits = [0.01 1.5];
            app.AminSlider.ValueChangedFcn = createCallbackFcn(app, @AminSliderValueChanged, true);
            app.AminSlider.Value = 0.01;
            app.AmaxSliderLabel = uilabel(app.UIFigure);
            app.AmaxSliderLabel.Text = 'Amax';
            app.AmaxSlider = uislider(app.UIFigure);
            app.AmaxSlider.Limits = [0.01 1.5];
            app.AmaxSlider.ValueChangedFcn = createCallbackFcn(app, @AmaxSliderValueChanged, true);
            app.AmaxSlider.Value = 0.01;
            app.PHIminSliderLabel = uilabel(app.UIFigure);
            app.PHIminSliderLabel.Text = 'PHImin';
            app.PHIminSlider = uislider(app.UIFigure);
            app.PHIminSlider.Limits = [0.01 1.5];
            app.PHIminSlider.ValueChangedFcn = createCallbackFcn(app, @PHIminSliderValueChanged, true);
            app.PHIminSlider.Value = 0.01;
            app.PHImaxSliderLabel = uilabel(app.UIFigure);
            app.PHImaxSliderLabel.Text = 'PHImax';
            app.PHImaxSlider = uislider(app.UIFigure);
            app.PHImaxSlider.Limits = [0.01 1.5];
            app.PHImaxSlider.ValueChangedFcn = createCallbackFcn(app, @PHImaxSliderValueChanged, true);
            app.PHImaxSlider.Value = 0.01;
            app.tSliderLabel = uilabel(app.UIFigure);
            app.tSliderLabel.Text = 't';
            app.tSlider = uislider(app.UIFigure);
            app.tSlider.Limits = [1 1070];
            app.tSlider.ValueChangedFcn = createCallbackFcn(app, @tSliderValueChanged, true);
            app.tSlider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'A_s (temporal modes)')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'As')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1270];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PHI_s (spatial coefficients)')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'PHIs')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 1270];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pod

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end