classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        FileMenu            matlab.ui.container.Menu
        OpenMenu            matlab.ui.container.Menu
        timeSliderLabel     matlab.ui.control.Label
        timeSlider          matlab.ui.control.Slider
        kSliderLabel        matlab.ui.control.Label
        kSlider             matlab.ui.control.Slider
        FieldListBoxLabel   matlab.ui.control.Label
        FieldListBox        matlab.ui.control.ListBox
        timeEditFieldLabel  matlab.ui.control.Label
        timeEditField       matlab.ui.control.NumericEditField
        kEditFieldLabel     matlab.ui.control.Label
        kEditField          matlab.ui.control.NumericEditField
        UIAxes              matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataCube % Description
        evelv % Description
        k % Description
        PHI_s % Description
        PHI % Description
        U % Description
        st % Description
        nt % Description
        A_s % Description
        t % Description
        file % Description
        path % Description
        dataset % Description
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function results = loaddata(app)
            
            %bdir='/Users/mikegriffiths/proj';
            %rdirectory='/washmc-data/uni1';
            %directory=[bdir,rdirectory,'/'];
            %matfile=[directory,'hhzverustime.mat'];
            matfile=[app.path,app.file];
            app.DataCube=load(matfile);
            
             %'evelchrom_vh', 'eveltran_vh', 'evelcor_vh', 
             % 'evel2Mm_vh', 'evel1Mm_vh', 'evelp5Mm'};

            switch(app.dataset)
              case 'evelchrom_bz'
                    app.evelv=app.DataCube.evelchrom_bz;
              case 'eveltran_bz'
                    app.evelv=app.DataCube.eveltran_bz;
              case 'evelcor_bz'
                    app.evelv=app.DataCube.evelcor_bz;
              case 'evelchrom_vh'
                    app.evelv=app.DataCube.evelchrom_vh;
              case 'eveltran_vh'
                    app.evelv=app.DataCube.eveltran_vh;
              case 'evelcor_vh'
                    app.evelv=app.DataCube.evelcor_vh; 
              case 'evelp5Mm_vh'
                    app.evelv=app.DataCube.evelvp5Mm_vh;
              case 'evel1Mm_vh'
                    app.evelv=app.DataCube.evelv1Mm_vh;
              case 'evel2Mm_vh'
                    app.evelv=app.DataCube.evelv2Mm_vh;
              case 'evelp5Mm_bz'
                    app.evelv=app.DataCube.evelvp5Mm_bz;
              case 'evel1Mm_bz'
                    app.evelv=app.DataCube.evelv1Mm_bz;
              case 'evel2Mm_bz'
                    app.evelv=app.DataCube.evelv2Mm_bz;                     
              end
            
            app.nt=1270; %635 for uni4   %1070 for uni1
        end
        
        function results = pod(app)
            
                switch(app.dataset)
              case 'evelchrom_bz'
                    app.evelv=app.DataCube.evelchrom_bz;
              case 'eveltran_bz'
                    app.evelv=app.DataCube.eveltran_bz;
              case 'evelcor_bz'
                    app.evelv=app.DataCube.evelcor_bz;
              case 'evelchrom_vh'
                    app.evelv=app.DataCube.evelchrom_vh;
              case 'eveltran_vh'
                    app.evelv=app.DataCube.eveltran_vh;
              case 'evelcor_vh'
                    app.evelv=app.DataCube.evelcor_vh; 
              case 'evelp5Mm_vh'
                    app.evelv=app.DataCube.evelvp5Mm_v;
              case 'evel1Mm_vh'
                    app.evelv=app.DataCube.evelv1Mm_v;
              case 'evel2Mm_vh'
                    app.evelv=app.DataCube.evelv2Mm_v;
              case 'evelp5Mm_bz'
                    app.evelv=app.DataCube.evelvp5Mm_bz;
              case 'evel1Mm_bz'
                    app.evelv=app.DataCube.evelv1Mm_bz;
              case 'evel2Mm_bz'
                    app.evelv=app.DataCube.evelv2Mm_bz;                     
              end            


            S=app.evelv(:,:,1:app.nt);
            %proper orthogonal decomposition
            % https://depositonce.tu-berlin.de/bitstream/11303/9456/5/podnotes_aiaa2019.pdf

            % Create snapshot matrix
            Nt = length(S(1,1,:));
            S = reshape(permute(S, [3 2 1]), Nt, [ ]); % Reshape data into a matrix S with Nt rows
            app.U = S - repmat(mean(S,1), Nt, 1); % Subtract the temporal mean from each row

            % Create correlation matrix
            C_s = (app.U*(app.U)')/(Nt-1);


            % Solve eigenvalue problem
            [app.A_s LAM_s] = eig(C_s,'vector');
            % Sort eigenvalues and eigenvectors
            [lambda_s,ilam_s] = sort(LAM_s,'descend');
            app.A_s = app.A_s(:, ilam_s); % These are the temporal modes
            % Calculate spatial coefficients
            app.PHI_s = (app.U)'*app.A_s;
            
            
        end
        

    end
    
    methods (Access = public)
        
        function results = construct(app)
            %app.k = 105; % for example
            
            % Normalization to match direct and snapshot modes (optional)
            app.PHI = normc(app.PHI_s); % Spatial modes
            A = app.U*app.PHI; % Time coefficients
            Utilde_k = A(:,app.k)*app.PHI(:,app.k)'; % Reconstruction on mode k


            app.st=reshape(Utilde_k,[app.nt,124,124]);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            bdir='/Users/mikegriffiths/proj';
            rdirectory='/washmc-data/uni6';
            directory=[bdir,rdirectory,'/'];
            %matfile=[directory,'hhzverustime.mat'];
            matfile=['hhzverustime.mat'];
            app.file=matfile
            app.path=directory
            app.dataset='evelchrom_bz';
            loaddata(app)
            app.k=5;
            pod(app)
            construct(app)
        end

        % Value changed function: timeSlider
        function timeSliderValueChanged(app, event)
            value = app.timeSlider.Value;
            construct(app)
            app.t=int32(value);
            s1=surf(app.UIAxes, reshape(app.st(int32((value)),:,:),[124,124]));
            s1.EdgeColor = 'none';
            view(app.UIAxes,2);
            colorbar(app.UIAxes)
            
            s2=surf(app.UIAxes2, reshape(app.U(int32((value)),:,:),[124,124]));
            s2.EdgeColor = 'none';
            view(app.UIAxes2,2);
            colorbar(app.UIAxes2);
            
            app.timeEditField.Value = value ;
            
            %plot(app.UIAxes, value*peaks)
            %app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: kSlider
        function kslidervaluechanged(app, event)
            value = app.kSlider.Value;
            app.k=int32(value);
            construct(app);
            
            s1=surf(app.UIAxes, reshape(app.st(int32((app.t)),:,:),[124,124]));
            s1.EdgeColor = 'none';
            view(app.UIAxes,2);
            colorbar(app.UIAxes)
            
            s2=surf(app.UIAxes2, reshape(app.U(int32((value)),:,:),[124,124]));
            s2.EdgeColor = 'none';
            view(app.UIAxes2,2);
            colorbar(app.UIAxes2);
            
            app.kEditField.Value = value;
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [app.file,app.path] = uigetfile
            
            loaddata(app)
            app.k=5;
            pod(app)
            construct(app)            
        end

        % Value changed function: FieldListBox
        function FieldListBoxValueChanged(app, event)
            app.dataset = app.FieldListBox.Value;
            
            
            app.k=5;
            pod(app)
            construct(app)               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.FileMenu = uimenu(app.Menu);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.timeSliderLabel = uilabel(app.UIFigure);
            app.timeSliderLabel.Text = 'time';
            app.timeSlider = uislider(app.UIFigure);
            app.timeSlider.Limits = [1 1270];
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderValueChanged, true);
            app.timeSlider.Value = 1;
            app.kSliderLabel = uilabel(app.UIFigure);
            app.kSliderLabel.Text = 'k';
            app.kSlider = uislider(app.UIFigure);
            app.kSlider.Limits = [1 1270];
            app.kSlider.ValueChangedFcn = createCallbackFcn(app, @kslidervaluechanged, true);
            app.kSlider.Value = 1;
            app.FieldListBoxLabel = uilabel(app.UIFigure);
            app.FieldListBoxLabel.Text = {'Field'; ''};
            app.FieldListBox = uilistbox(app.UIFigure);
            app.FieldListBox.Items = {'evelchrom_vh', 'eveltran_vh', 'evelcor_vh', 'evel2Mm_vh', 'evel1Mm_vh', 'evelp5Mm_vh', 'evelchrom_bz', 'eveltran_bz', 'evelcor_bz', 'evel2Mm_bz', 'evel1Mm_bz', 'evelp5Mm_bz', ''};
            app.FieldListBox.ValueChangedFcn = createCallbackFcn(app, @FieldListBoxValueChanged, true);
            app.FieldListBox.Value = 'evelchrom_vh';
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reconstruction Using kth Mode at Time t')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 124];
            app.UIAxes.YLim = [0 124];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Field')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end